classdef EStop_Popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        checkLabel       matlab.ui.control.Label
        NoButton         matlab.ui.control.Button
        YesButton        matlab.ui.control.Button
        BackgroundImage  matlab.ui.control.Image
    end

    
    properties (Access = public)
        confirmation = 'Unconfirmed'% Description
    end
    
    properties (Access = private)
        callingApp % Main app
    end
    
    methods (Access = public)
        
        function resetPopUp(app)
            app.confirmation = 'Unconfirmed';
        end
        
        function result = getConfirmation(app)
            result = app.confirmation;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
           app.callingApp = caller;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.confirmation = 'Yes';
            popUpReturn(app.callingApp, app.confirmation);
            delete(app);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.confirmation = 'No';
            popUpReturn(app.callingApp, app.confirmation);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BackgroundImage = uiimage(app.UIFigure);
            app.BackgroundImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'elmo fire.jpg');
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes, continue';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No, stop program';
            app.checkLabel = uilabel(app.UIFigure);
            app.checkLabel.Text = 'Do you want to resume the current program?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EStop_Popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end