classdef RobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PizzaMenuPanel             matlab.ui.container.Panel
        PrepareLabel               matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PizzaSelectionButtonGroup  matlab.ui.container.ButtonGroup
        FourCheesesButton          matlab.ui.control.RadioButton
        PepperoniButton            matlab.ui.control.RadioButton
        HawaiianButton             matlab.ui.control.RadioButton
        MargheritaButton           matlab.ui.control.RadioButton
        RobotControlPanel          matlab.ui.container.Panel
        RobotDropDown              matlab.ui.control.DropDown
        RobotDropDownLabel         matlab.ui.control.Label
        CartesianControlPanel      matlab.ui.container.Panel
        ZButtonDown                matlab.ui.control.Button
        YButtonDown                matlab.ui.control.Button
        XButtonDown                matlab.ui.control.Button
        ZButtonUp                  matlab.ui.control.Button
        YButtonUp                  matlab.ui.control.Button
        XButtonUp                  matlab.ui.control.Button
        RobotJointControlPanel     matlab.ui.container.Panel
        Q6Value                    matlab.ui.control.EditField
        Q5Value                    matlab.ui.control.EditField
        Q4Value                    matlab.ui.control.EditField
        Q3Value                    matlab.ui.control.EditField
        Q2Value                    matlab.ui.control.EditField
        Q1Value                    matlab.ui.control.EditField
        Q6Slider                   matlab.ui.control.Slider
        Q6SliderLabel              matlab.ui.control.Label
        Q5Slider                   matlab.ui.control.Slider
        Q5SliderLabel              matlab.ui.control.Label
        Q4Slider                   matlab.ui.control.Slider
        Q4SliderLabel              matlab.ui.control.Label
        Q3Slider                   matlab.ui.control.Slider
        Q3SliderLabel              matlab.ui.control.Label
        Q2Slider                   matlab.ui.control.Slider
        Q2SliderLabel              matlab.ui.control.Label
        Q1Slider                   matlab.ui.control.Slider
        Q1SliderLabel              matlab.ui.control.Label
        EStop                      matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        chefBot;
        slicerBot;
        currentBot;
        kitchen;
        currentPizza = 'Margherita';
        buttonPin = 'D2';
        arduinoObj; 
        demoStep = 0;
        totalSteps = 16;
        popup;
        cancelDemo;
        analogEStop = 0;

        % Prepare Variables
        ur3home = deg2rad([-120,-90,60,-60,-90,0]);
        cutterpos = transl([-1.35,-0.7,0.6]);
        IRBhome = [-pi/2 -pi/4 3*pi/4 0 0 0];
        scoopoffset = [0,0.35,0.05];
        makeflat = trotx(pi/2) * trotz(pi/2);
        basepickup = transl([0.45,-0.45,0.4] + [-0.24,0.24,0.05]) * trotz(pi/4); % coming at the base at a corner
        basepos = transl(0.6,-0.7,0.4);
        basecartmove = transl([0.36,-0.46,0.45]) * trotz(pi/4);
        disp1 = transl([0.3,-1,0.45] + [0,0.35,0.05]);
        disp2 = transl([0,-1,0.45] + [0,0.35,0.05]);
        disp3 = transl([-0.3,-1,0.45] + [0,0.35,0.05]);
        disp4 = transl([-0.6,-1,0.45] + [0,0.35,0.05]);
        dispwaypoint = [0,-1,0,0; 0,0,-1,-0.2128; 1,0,0,0.7382; 0,0,0,1]
        cutpos = transl([-0.8,-0.7,0.45] + [0.24,0.24,0.05]) * trotz(-pi/4);
        oven = transl([0.82,0,0.55] + [-0.35,0,0.05]) * trotz(pi/2);
        conveyor = transl([-1.1,-0.3,0.45]  + [0.35,0,0.05]) * trotz(-pi/2);
    end
    
    methods (Access = private)
        
        function UpdateSliders(app)

            joints = app.currentBot.robot.model.getpos();
            app.Q1Slider.Value = rad2deg(joints(1));
            app.Q2Slider.Value = rad2deg(joints(2));
            app.Q3Slider.Value = rad2deg(joints(3));
            app.Q4Slider.Value = rad2deg(joints(4));
            app.Q5Slider.Value = rad2deg(joints(5));
            app.Q6Slider.Value = rad2deg(joints(6));

            app.Q1Value.Value = num2str(round(app.Q1Slider.Value));
            app.Q2Value.Value = num2str(round(app.Q2Slider.Value));
            app.Q3Value.Value = num2str(round(app.Q3Slider.Value));
            app.Q4Value.Value = num2str(round(app.Q4Slider.Value));
            app.Q5Value.Value = num2str(round(app.Q5Slider.Value));
            app.Q6Value.Value = num2str(round(app.Q6Slider.Value));

        end
        
        function readArduino(app, src, ~)
            data = readline(src);
            numData = str2num(data);
            if numData == 1
                app.analogEStop = 1;
                if app.EStop.Value
                    flush(app.arduinoObj);
                else
                    app.EStop.Value = 1;
                    app.EStopPressed();
                    flush(app.arduinoObj);
                end
            else 
                app.analogEStop = 0;
            end
        end
    end
    
    methods (Access = public)
        
        function popUpReturn(app, conf)
            switch conf
                case 'Yes'
                    disp('continued');
                    app.chefBot.eStop = 0;
                    app.slicerBot.eStop = 0;
                case 'No'
                    disp('cancelled');
                    app.cancelDemo = 1;
                    app.chefBot.cancelDemo = 1;
                    app.slicerBot.cancelDemo = 1;
                    app.chefBot.eStop = 0;
                    app.slicerBot.eStop = 0;
                    app.RobotControlPanel.Enable = "on";
                    app.PizzaMenuPanel.Enable = "on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app)
            clf;
            clc;
            hold on;

            app.kitchen = KitchenDemo();
            app.chefBot = ChefBot();
            app.slicerBot = SlicerBot();
            app.currentBot = app.chefBot;
            app.robotSelection(app.RobotDropDown);
            PlaceObject('environment.ply');
            try
                app.arduinoObj= serialport("COM5",9600);
                configureTerminator(app.arduinoObj,"CR/LF");
                flush(app.arduinoObj);
                app.arduinoObj.UserData = struct("Data",[],"Count",1);
                configureCallback(app.arduinoObj,"terminator",@app.readArduino);
            catch ME
                msg = ME.message;
                disp(['Error: ' msg]);
            end
        end

        % Image clicked function: Image
        function PreparePizza(app, event)
            disp("Preparing pizza...")

            % disable all robot control functions to avoid conflicts and
            % errors caused by a user
            app.RobotControlPanel.Enable = "off";
            app.PizzaMenuPanel.Enable = "off";

            % base = Pizza(app.basepos,'Base');
            % 
            % % move to home position
            % app.chefBot.Home();
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % move to pizza base
            % app.chefBot.JointMove(app.basepickup*app.makeflat);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % cart move to pizza base
            % app.chefBot.CartesianMove(app.basecartmove * app.makeflat);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % move to dispensor
            % switch app.currentPizza
            %     case 'Margherita'
            %         % ////////////    RMRC    ////////////
            %         qMatrix = app.chefBot.CalcMotion(app.disp1*app.makeflat);
            %         qMatrix = app.chefBot.RMRC(qMatrix);
            %         app.chefBot.stepCalcMotion(qMatrix,base);
            %     case 'Hawaiian'
            %         % ////////////    RMRC    ////////////
            %         qMatrix = app.chefBot.CalcMotion(app.disp2*app.makeflat);
            %         qMatrix = app.chefBot.RMRC(qMatrix);
            %         app.chefBot.stepCalcMotion(qMatrix,base);
            %     case 'Pepperoni'
            %         % ////////////    RMRC    ////////////
            %         qMatrix = app.chefBot.CalcMotion(app.disp3*app.makeflat);
            %         qMatrix = app.chefBot.RMRC(qMatrix);
            %         app.chefBot.stepCalcMotion(qMatrix,base);
            %     case 'Four Cheeses'
            %         % ////////////    RMRC    ////////////
            %         qMatrix = app.chefBot.CalcMotion(app.disp4*app.makeflat);
            %         qMatrix = app.chefBot.RMRC(qMatrix);
            %         app.chefBot.stepCalcMotion(qMatrix,base);
            % end
            % app.demoStep = 3;
            % 
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % add toppings
            % basetr = base.model.base.T;
            % pizza = Pizza(basetr,app.currentPizza);
            % delete(base)
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % exit from dispensor
            % tf = app.chefBot.gettransform(0);
            % tf(2,4) = (tf(2,4) + 0.3);
            % app.chefBot.CartesianMove(tf,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % way point
            % app.chefBot.JointMove(app.dispwaypoint,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % way point above oven
            % tf = app.chefBot.gettransform(deg2rad([90 -45 105 0 30 0]));
            % app.chefBot.JointMove(tf,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % lower to oven level
            % tf = app.chefBot.gettransform(0);
            % tf(3,4) = (tf(3,4) - 0.3);
            % app.chefBot.CartesianMove(tf,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % move into oven
            % app.chefBot.JointMove(app.oven * app.makeflat,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % letting him cook
            % out_oven = app.chefBot.gettransform(0);
            % out_oven(1,4) = (out_oven(1,4) - 0.4);
            % app.chefBot.CartesianMove(out_oven);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % pause(2.5);
            % in_oven = app.chefBot.gettransform(0);
            % in_oven(1,4) = (in_oven(1,4) + 0.4);
            % app.chefBot.CartesianMove(in_oven)
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % out_oven = app.chefBot.gettransform(0);
            % out_oven(1,4) = (out_oven(1,4) - 0.4);
            % app.chefBot.CartesianMove(out_oven,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % cut position                                                  
            % % ////////////    RMRC    ////////////
            % qMatrix = app.chefBot.CalcMotion(app.cutpos * app.makeflat);
            % qMatrix = app.chefBot.RMRC(qMatrix);
            % app.chefBot.stepCalcMotion(qMatrix,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end

            %           ///////////////// CUT WITH UR3 /////////////////
            cutter = 1;
            % home
            app.slicerBot.Home('open',0);
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            % pickup cutter
            app.slicerBot.JointMove(app.cutterpos*troty(pi),'open',0);
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            % home
            app.slicerBot.Home('closed',cutter)
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            % cut path
            cutMatrix = app.slicerBot.CalculateCut();
            app.slicerBot.stepSlicing(cutMatrix,cutter);
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            % place cutter
            app.slicerBot.JointMove(app.cutterpos*troty(pi),'closed',cutter);
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            % home
            app.slicerBot.Home('open',0);
            if app.cancelDemo
                app.chefBot.cancelDemo = 0;
                app.slicerBot.cancelDemo = 0;
                app.cancelDemo = 0;
                return;
            end
            %           ///////////////// CUT WITH UR3 /////////////////

            % % move to conveyor
            % app.chefBot.JointMove(app.conveyor * app.makeflat,pizza);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % place on conveyor
            % tf = app.chefBot.gettransform(0);
            % tf(1,4) = (tf(1,4) + 0.7);
            % app.chefBot.CartesianMove(tf);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % move pizza
            % q1 = pizza.model.getpos;
            % tf = pizza.model.fkine(q1).T;
            % transform = tf;
            % transform(1:3,4) = (transform(1:3,4) + [0,1.9,0]');
            % tfMatrix = ctraj(tf,transform,30);
            % for i = 1:30
            %     pizza.model.base = tfMatrix(:,:,i);
            %     pizza.model.animate(0)
            %     drawnow()
            % end
            % delete(pizza);
            % 
            % % return from conveyor
            % tf = app.chefBot.gettransform(0);
            % tf(3,4) = (tf(3,4) + 0.3);
            % app.chefBot.CartesianMove(tf);
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end
            % 
            % % move to home position
            % app.chefBot.Home();
            % if app.cancelDemo
            %     app.chefBot.cancelDemo = 0;
            %     app.slicerBot.cancelDemo = 0;
            %     app.cancelDemo = 0;
            %     return;
            % end

            app.demoStep = 0;

            pause(4);
            app.RobotControlPanel.Enable = "on";
            app.PizzaMenuPanel.Enable = "on";
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.Q6Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(6,event.Value);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.Q5Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(5,event.Value);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.Q4Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(4,event.Value);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.Q3Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(3,event.Value);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.Q2Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(2,event.Value);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.Q1Value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(1,event.Value);
        end

        % Button pushed function: XButtonUp
        function XButtonUpPushed(app, event)
            app.currentBot.CartJogRobot('+x');
            app.UpdateSliders();
        end

        % Button pushed function: XButtonDown
        function XButtonDownPushed(app, event)
            app.currentBot.CartJogRobot('-x');
            app.UpdateSliders();
        end

        % Button pushed function: YButtonUp
        function YButtonUpPushed(app, event)
            app.currentBot.CartJogRobot('+y');
            app.UpdateSliders();
        end

        % Button pushed function: YButtonDown
        function YButtonDownPushed(app, event)
            app.currentBot.CartJogRobot('-y');
            app.UpdateSliders();
        end

        % Button pushed function: ZButtonUp
        function ZButtonUpPushed(app, event)
            app.currentBot.CartJogRobot('+z');
            app.UpdateSliders();
        end

        % Button pushed function: ZButtonDown
        function ZButtonDownPushed(app, event)
            app.currentBot.CartJogRobot('-z');
            app.UpdateSliders();
        end

        % Value changed function: RobotDropDown
        function robotSelection(app, event)
            value = event.Value;
            switch value
                case 'IRB-1200 (Guido)'
                    app.currentBot = app.chefBot;
                    disp('Now controlling IRB-1200 (Guido)');
                case 'UR3e (Luigi)'
                    app.currentBot = app.slicerBot;
                    disp('Now controlling UR3e (Luigi)');
            end

            app.Q1Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(1,1));
            app.Q1Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(1,2));

            app.Q2Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(2,1));
            app.Q2Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(2,2));

            app.Q3Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(3,1));
            app.Q3Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(3,2));

            app.Q4Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(4,1));
            app.Q4Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(4,2));

            app.Q5Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(5,1));
            app.Q5Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(5,2));

            app.Q6Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(6,1));
            app.Q6Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(6,2));

            app.UpdateSliders();
        end

        % Value changed function: Q1Value
        function Q1ValueChanged(app, event)
            value = app.Q1Value.Value;
            doubleval = str2num(value);
            try
                app.Q1Slider.Value = doubleval;
                app.Q1SliderValueChanging(app.Q1Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q2Value
        function Q2ValueChanged(app, event)
            value = app.Q2Value.Value;
            doubleval = str2num(value);
            try
                app.Q2Slider.Value = doubleval;
                app.Q2SliderValueChanging(app.Q2Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q3Value
        function Q3ValueChanged(app, event)
            value = app.Q3Value.Value;
            doubleval = str2num(value);
            try
                app.Q3Slider.Value = doubleval;
                app.Q3SliderValueChanging(app.Q3Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q4Value
        function Q4ValueChanged(app, event)
            value = app.Q4Value.Value;
            doubleval = str2num(value);
            try
                app.Q4Slider.Value = doubleval;
                app.Q4SliderValueChanging(app.Q4Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q5Value
        function Q5ValueChanged(app, event)
            value = app.Q5Value.Value;
            doubleval = str2num(value);
            try
                app.Q5Slider.Value = doubleval;
                app.Q5SliderValueChanging(app.Q5Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q6Value
        function Q6ValueChanged(app, event)
            value = app.Q6Value.Value;
            doubleval = str2num(value);
            try
                app.Q6Slider.Value = doubleval;
                app.Q6SliderValueChanging(app.Q6Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: EStop
        function EStopPressed(app, event)
            value = app.EStop.Value;
        
            if value == 1
                % E-stop is pressed
                app.RobotControlPanel.Enable = "off";
                app.PizzaMenuPanel.Enable = "off";
                disp('Estop pressed');
                app.chefBot.eStop = value;
                app.slicerBot.eStop = value;
            end
        
            if value == 0
                % E-stop is released
                % Open the pop-up confirmation dialog
                if app.analogEStop
                    disp('Release Mechanical Estop Button');
                    app.EStop.Value = 1;
                else
                    disp('Estop released');
                    app.popup = EStop_Popup(app);
                end
            end
        end

        % Selection changed function: PizzaSelectionButtonGroup
        function PizzaSelection(app, event)
            app.currentPizza = app.PizzaSelectionButtonGroup.SelectedObject.Text;        
        end

        % Close request function: UIFigure
        function closeWindow(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeWindow, true);
            app.EStop = uibutton(app.UIFigure, 'state');
            app.EStop.ValueChangedFcn = createCallbackFcn(app, @EStopPressed, true);
            app.EStop.Icon = fullfile(pathToMLAPP, 'Images', 'Asset 31.jpeg');
            app.EStop.Text = '';
            app.RobotControlPanel = uipanel(app.UIFigure);
            app.RobotControlPanel.Title = 'Robot Control Panel';
            app.RobotJointControlPanel = uipanel(app.RobotControlPanel);
            app.RobotJointControlPanel.Title = 'Robot Joint Control';
            app.Q1SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.RobotJointControlPanel);
            app.Q1Slider.MajorTicks = [];
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [];
            app.Q1Slider.Value = 50;
            app.Q2SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.RobotJointControlPanel);
            app.Q2Slider.MajorTicks = [];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2Slider.MinorTicks = [];
            app.Q2Slider.Value = 50;
            app.Q3SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.RobotJointControlPanel);
            app.Q3Slider.MajorTicks = [];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3Slider.MinorTicks = [];
            app.Q3Slider.Value = 50;
            app.Q4SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.RobotJointControlPanel);
            app.Q4Slider.MajorTicks = [];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4Slider.MinorTicks = [];
            app.Q4Slider.Value = 50;
            app.Q5SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.RobotJointControlPanel);
            app.Q5Slider.MajorTicks = [];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5Slider.MinorTicks = [];
            app.Q5Slider.Value = 50;
            app.Q6SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.RobotJointControlPanel);
            app.Q6Slider.MajorTicks = [];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6Slider.MinorTicks = [];
            app.Q6Slider.Value = 50;
            app.Q1Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q1Value.ValueChangedFcn = createCallbackFcn(app, @Q1ValueChanged, true);
            app.Q1Value.Value = '0';
            app.Q2Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q2Value.ValueChangedFcn = createCallbackFcn(app, @Q2ValueChanged, true);
            app.Q2Value.Value = '0';
            app.Q3Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q3Value.ValueChangedFcn = createCallbackFcn(app, @Q3ValueChanged, true);
            app.Q3Value.Value = '0';
            app.Q4Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q4Value.ValueChangedFcn = createCallbackFcn(app, @Q4ValueChanged, true);
            app.Q4Value.Value = '0';
            app.Q5Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q5Value.ValueChangedFcn = createCallbackFcn(app, @Q5ValueChanged, true);
            app.Q5Value.Value = '0';
            app.Q6Value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q6Value.ValueChangedFcn = createCallbackFcn(app, @Q6ValueChanged, true);
            app.Q6Value.Value = '0';
            app.CartesianControlPanel = uipanel(app.RobotControlPanel);
            app.CartesianControlPanel.Title = 'Cartesian Control';
            app.XButtonUp = uibutton(app.CartesianControlPanel, 'push');
            app.XButtonUp.ButtonPushedFcn = createCallbackFcn(app, @XButtonUpPushed, true);
            app.XButtonUp.Text = '+X';
            app.YButtonUp = uibutton(app.CartesianControlPanel, 'push');
            app.YButtonUp.ButtonPushedFcn = createCallbackFcn(app, @YButtonUpPushed, true);
            app.YButtonUp.Text = '+Y';
            app.ZButtonUp = uibutton(app.CartesianControlPanel, 'push');
            app.ZButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ZButtonUpPushed, true);
            app.ZButtonUp.Text = '+Z';
            app.XButtonDown = uibutton(app.CartesianControlPanel, 'push');
            app.XButtonDown.ButtonPushedFcn = createCallbackFcn(app, @XButtonDownPushed, true);
            app.XButtonDown.Text = '-X';
            app.YButtonDown = uibutton(app.CartesianControlPanel, 'push');
            app.YButtonDown.ButtonPushedFcn = createCallbackFcn(app, @YButtonDownPushed, true);
            app.YButtonDown.Text = '-Y';
            app.ZButtonDown = uibutton(app.CartesianControlPanel, 'push');
            app.ZButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ZButtonDownPushed, true);
            app.ZButtonDown.Text = '-Z';
            app.RobotDropDownLabel = uilabel(app.RobotControlPanel);
            app.RobotDropDownLabel.Text = 'Robot:';
            app.RobotDropDown = uidropdown(app.RobotControlPanel);
            app.RobotDropDown.Items = {'IRB-1200 (Guido)', 'UR3e (Luigi)'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @robotSelection, true);
            app.RobotDropDown.Value = 'IRB-1200 (Guido)';
            app.PizzaMenuPanel = uipanel(app.UIFigure);
            app.PizzaMenuPanel.Title = 'Pizza Menu';
            app.PizzaSelectionButtonGroup = uibuttongroup(app.PizzaMenuPanel);
            app.PizzaSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PizzaSelection, true);
            app.PizzaSelectionButtonGroup.Title = 'Pizza Selection';
            app.MargheritaButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.MargheritaButton.Text = 'Margherita';
            app.MargheritaButton.Value = true;
            app.HawaiianButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.HawaiianButton.Text = 'Hawaiian';
            app.PepperoniButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.PepperoniButton.Text = 'Pepperoni';
            app.FourCheesesButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.FourCheesesButton.Text = 'Four Cheeses';
            app.Image = uiimage(app.PizzaMenuPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @PreparePizza, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'elmo fire.jpg');
            app.PrepareLabel = uilabel(app.PizzaMenuPanel);
            app.PrepareLabel.Text = ' Prepare🤌';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end