classdef RobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PizzaMenuPanel             matlab.ui.container.Panel
        PrepareLabel               matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PizzaSelectionButtonGroup  matlab.ui.container.ButtonGroup
        FourCheesesButton          matlab.ui.control.RadioButton
        PepperoniButton            matlab.ui.control.RadioButton
        HawaiianButton             matlab.ui.control.RadioButton
        MargheritaButton           matlab.ui.control.RadioButton
        RobotControlPanel          matlab.ui.container.Panel
        RobotDropDown              matlab.ui.control.DropDown
        RobotDropDownLabel         matlab.ui.control.Label
        CartesianControlPanel      matlab.ui.container.Panel
        ZButton_2                  matlab.ui.control.Button
        YButton_2                  matlab.ui.control.Button
        XButton_2                  matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        RobotJointControlPanel     matlab.ui.container.Panel
        Q6value                    matlab.ui.control.EditField
        Q5value                    matlab.ui.control.EditField
        Q4value                    matlab.ui.control.EditField
        Q3value                    matlab.ui.control.EditField
        Q2value                    matlab.ui.control.EditField
        Q1value                    matlab.ui.control.EditField
        Q6Slider                   matlab.ui.control.Slider
        Q6SliderLabel              matlab.ui.control.Label
        Q5Slider                   matlab.ui.control.Slider
        Q5SliderLabel              matlab.ui.control.Label
        Q4Slider                   matlab.ui.control.Slider
        Q4SliderLabel              matlab.ui.control.Label
        Q3Slider                   matlab.ui.control.Slider
        Q3SliderLabel              matlab.ui.control.Label
        Q2Slider                   matlab.ui.control.Slider
        Q2SliderLabel              matlab.ui.control.Label
        Q1Slider                   matlab.ui.control.Slider
        Q1SliderLabel              matlab.ui.control.Label
        Button                     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        chefBot;
        slicerBot; % Description
        currentBot;
        kitchen;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onStartUp(app)
            app.kitchen = KitchenDemo();
            app.chefBot = ChefBot();
            app.slicerBot = SlicerBot();
            app.currentBot = app.chefBot;
            app.robotSelection(app.RobotDropDown);
        end

        % Image clicked function: Image
        function PreparePizza(app, event)
            disp("It'sa timeatocooka")
            % disable all robot control functions to avoid conflicts and
            % errors caused by a user
            % identify which pizza has been selected and then create a
            % class object of that pizza with a case decision
            % run the demo to make the pizza from within here, process
            % should be as follows...
            % - Pizza comes travels in on conveyor belt. It is in the
            % custom designed pizza pan
            % - Chefbot  grips the pizza pan and moves it under the
            % correct dispenser to have the ingredients 'dispensed' onto it
            % - The topping .ply file is added onto the pizza/the base
            % pizza is swapped out for a topped pizza.
            % - the pizza is moved into the oven and 'cooked' for a short
            % period of time. 
            % - Chefbot removes the pizza and puts it on the conveyor belt
            % in front of slicerBot. Chefbot holds onto it
            % - Once Chefbot is out of the way, slicerBot cuts the pizza
            % and it is sent back out on the conveyor belt
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.Q6value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(6,event.Value);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.Q5value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(5,event.Value);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.Q4value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(4,event.Value);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.Q3value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(3,event.Value);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.Q2value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(2,event.Value);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.Q1value.Value = num2str(round(event.Value));
            app.currentBot.JointJogRobot(1,event.Value);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+x');
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-x');
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+y');
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-y');
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+z');
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-z');
        end

        % Value changed function: RobotDropDown
        function robotSelection(app, event)
            value = event.Value;
            switch value
                case 'IRB-1200 (Guido)'
                    app.currentBot = app.chefBot;
                    disp('Now controlling IRB-1200 (Guido)');
                case 'UR3e (Luigi)'
                    app.currentBot = app.slicerBot;
                    disp('Now controlling UR3e (Luigi)');
            end

            joints = app.currentBot.robot.model.getpos();
            app.Q1Slider.Value = joints(1);
            app.Q2Slider.Value = joints(2);
            app.Q3Slider.Value = joints(3);
            app.Q4Slider.Value = joints(4);
            app.Q5Slider.Value = joints(5);
            app.Q6Slider.Value = joints(6);

            app.Q1Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(1,1));
            app.Q1Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(1,2));

            app.Q2Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(2,1));
            app.Q2Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(2,2));

            app.Q3Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(3,1));
            app.Q3Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(3,2));

            app.Q4Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(4,1));
            app.Q4Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(4,2));

            app.Q5Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(5,1));
            app.Q5Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(5,2));

            app.Q6Slider.Limits(1) = rad2deg(app.currentBot.robot.model.qlim(6,1));
            app.Q6Slider.Limits(2) = rad2deg(app.currentBot.robot.model.qlim(6,2));

            app.Q1value.Value = num2str(round(rad2deg(app.Q1Slider.Value)));
            app.Q2value.Value = num2str(round(rad2deg(app.Q2Slider.Value)));
            app.Q3value.Value = num2str(round(rad2deg(app.Q3Slider.Value)));
            app.Q4value.Value = num2str(round(rad2deg(app.Q4Slider.Value)));
            app.Q5value.Value = num2str(round(rad2deg(app.Q5Slider.Value)));
            app.Q6value.Value = num2str(round(rad2deg(app.Q6Slider.Value)));
            
        end

        % Value changed function: Q1value
        function Q1ValueChanged(app, event)
            value = app.Q1value.Value;
            doubleval = str2num(value);
            try
                app.Q1Slider.Value = doubleval;
                app.Q1SliderValueChanging(app.Q1Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q2value
        function Q2ValueChanged(app, event)
            value = app.Q2value.Value;
            doubleval = str2num(value);
            try
                app.Q2Slider.Value = doubleval;
                app.Q2SliderValueChanging(app.Q2Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q3value
        function Q3ValueChanged(app, event)
            value = app.Q3value.Value;
            doubleval = str2num(value);
            try
                app.Q3Slider.Value = doubleval;
                app.Q3SliderValueChanging(app.Q3Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q4value
        function Q4ValueChanged(app, event)
            value = app.Q4value.Value;
            doubleval = str2num(value);
            try
                app.Q4Slider.Value = doubleval;
                app.Q4SliderValueChanging(app.Q4Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q5value
        function Q5ValueChanged(app, event)
            value = app.Q5value.Value;
            doubleval = str2num(value);
            try
                app.Q5Slider.Value = doubleval;
                app.Q5SliderValueChanging(app.Q5Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end

        % Value changed function: Q6value
        function Q6ValueChanged(app, event)
            value = app.Q6value.Value;
            doubleval = str2num(value);
            try
                app.Q6Slider.Value = doubleval;
                app.Q6SliderValueChanging(app.Q6Slider);
            catch
                disp("Value must be within robot joint limits!")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Icon = fullfile(pathToMLAPP, 'GUI', 'Images', 'Asset 31.jpeg');
            app.Button.Text = '';
            app.RobotControlPanel = uipanel(app.UIFigure);
            app.RobotControlPanel.Title = 'Robot Control Panel';
            app.RobotJointControlPanel = uipanel(app.RobotControlPanel);
            app.RobotJointControlPanel.Title = 'Robot Joint Control';
            app.Q1SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.RobotJointControlPanel);
            app.Q1Slider.MajorTicks = [];
            app.Q1Slider.MajorTickLabels = {''};
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [];
            app.Q1Slider.Value = 50;
            app.Q2SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.RobotJointControlPanel);
            app.Q2Slider.MajorTicks = [];
            app.Q2Slider.MajorTickLabels = {''};
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2Slider.MinorTicks = [];
            app.Q2Slider.Value = 50;
            app.Q3SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.RobotJointControlPanel);
            app.Q3Slider.MajorTicks = [];
            app.Q3Slider.MajorTickLabels = {''};
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3Slider.MinorTicks = [];
            app.Q3Slider.Value = 50;
            app.Q4SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.RobotJointControlPanel);
            app.Q4Slider.MajorTicks = [];
            app.Q4Slider.MajorTickLabels = {''};
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4Slider.MinorTicks = [];
            app.Q4Slider.Value = 50;
            app.Q5SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.RobotJointControlPanel);
            app.Q5Slider.MajorTicks = [];
            app.Q5Slider.MajorTickLabels = {''};
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5Slider.MinorTicks = [];
            app.Q5Slider.Value = 50;
            app.Q6SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.RobotJointControlPanel);
            app.Q6Slider.MajorTicks = [];
            app.Q6Slider.MajorTickLabels = {''};
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6Slider.MinorTicks = [];
            app.Q6Slider.Value = 50;
            app.Q1value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q1value.ValueChangedFcn = createCallbackFcn(app, @Q1ValueChanged, true);
            app.Q1value.Value = '0';
            app.Q2value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q2value.ValueChangedFcn = createCallbackFcn(app, @Q2ValueChanged, true);
            app.Q2value.Value = '0';
            app.Q3value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q3value.ValueChangedFcn = createCallbackFcn(app, @Q3ValueChanged, true);
            app.Q3value.Value = '0';
            app.Q4value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q4value.ValueChangedFcn = createCallbackFcn(app, @Q4ValueChanged, true);
            app.Q4value.Value = '0';
            app.Q5value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q5value.ValueChangedFcn = createCallbackFcn(app, @Q5ValueChanged, true);
            app.Q5value.Value = '0';
            app.Q6value = uieditfield(app.RobotJointControlPanel, 'text');
            app.Q6value.ValueChangedFcn = createCallbackFcn(app, @Q6ValueChanged, true);
            app.Q6value.Value = '0';
            app.CartesianControlPanel = uipanel(app.RobotControlPanel);
            app.CartesianControlPanel.Title = 'Cartesian Control';
            app.XButton = uibutton(app.CartesianControlPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.YButton = uibutton(app.CartesianControlPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.ZButton = uibutton(app.CartesianControlPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.XButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.ZButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.RobotDropDownLabel = uilabel(app.RobotControlPanel);
            app.RobotDropDownLabel.Text = 'Robot:';
            app.RobotDropDown = uidropdown(app.RobotControlPanel);
            app.RobotDropDown.Items = {'IRB-1200 (Guido)', 'UR3e (Luigi)'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @robotSelection, true);
            app.RobotDropDown.Value = 'IRB-1200 (Guido)';
            app.PizzaMenuPanel = uipanel(app.UIFigure);
            app.PizzaMenuPanel.Title = 'Pizza Menu';
            app.PizzaSelectionButtonGroup = uibuttongroup(app.PizzaMenuPanel);
            app.PizzaSelectionButtonGroup.Title = 'Pizza Selection';
            app.MargheritaButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.MargheritaButton.Text = 'Margherita';
            app.MargheritaButton.Value = true;
            app.HawaiianButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.HawaiianButton.Text = 'Hawaiian';
            app.PepperoniButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.PepperoniButton.Text = 'Pepperoni';
            app.FourCheesesButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.FourCheesesButton.Text = 'Four Cheeses';
            app.Image = uiimage(app.PizzaMenuPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @PreparePizza, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Images', 'elmo fire.jpg');
            app.PrepareLabel = uilabel(app.PizzaMenuPanel);
            app.PrepareLabel.Text = ' Prepare🤌';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @onStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end